classdef BaseDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        AreaButtonGroup           matlab.ui.container.ButtonGroup
        WestButton                matlab.ui.control.RadioButton
        MidwestButton             matlab.ui.control.RadioButton
        NortheastButton           matlab.ui.control.RadioButton
        SouthButton               matlab.ui.control.RadioButton
        USAButton                 matlab.ui.control.RadioButton
        SortingOptionButtonGroup  matlab.ui.container.ButtonGroup
        AscendingButton           matlab.ui.control.ToggleButton
        DescendingButton          matlab.ui.control.ToggleButton
        DropDown                  matlab.ui.control.DropDown
        RunButton                 matlab.ui.control.Button
        GraphDisplayButton        matlab.ui.control.Button
        SaveasTextFileButton      matlab.ui.control.Button
        Panel_2                   matlab.ui.container.Panel
        Panel_3                   matlab.ui.container.Panel
        Label                     matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        MedicareCostLabel         matlab.ui.control.Label
    end

    properties (Access = public)
        area;
    end
    
    methods (Access = private)
        
        function pick(app)
            if app.WestButton.Value == true
                app.area = 'West';
            elseif app.MidwestButton.Value == true
                app.area = 'Midwest';
            elseif app.NortheastButton.Value == true
                app.area = 'Northeast';
            elseif app.SouthButton.Value == true
                app.area = 'South';
            else
                app.area = 'USA';
            end
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunProg(app, event)
            app.Label.Text = 'Reading Dataset...';
            pick(app);
            [val, state] = runmain(app);
            t = app.UITable;
            dat = cell(length(state),2);
            for i = 1:length(state)
                dat{i,1} = state(i,1:2);
                dat{i,2} = val(i);
            end
            t.Data = dat;
        end

        % Button pushed function: GraphDisplayButton
        function GraphDisp(app, event)
            
        end

        % Button pushed function: SaveasTextFileButton
        function TxtSave(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.AreaButtonGroup = uibuttongroup(app.Panel);
            app.AreaButtonGroup.Title = 'Area';
            app.WestButton = uiradiobutton(app.AreaButtonGroup);
            app.WestButton.Text = 'West';
            app.MidwestButton = uiradiobutton(app.AreaButtonGroup);
            app.MidwestButton.Text = 'Midwest';
            app.NortheastButton = uiradiobutton(app.AreaButtonGroup);
            app.NortheastButton.Text = 'Northeast';
            app.SouthButton = uiradiobutton(app.AreaButtonGroup);
            app.SouthButton.Text = 'South';
            app.USAButton = uiradiobutton(app.AreaButtonGroup);
            app.USAButton.Text = 'U.S.A.';
            app.USAButton.Value = true;
            app.SortingOptionButtonGroup = uibuttongroup(app.Panel);
            app.SortingOptionButtonGroup.Title = 'Sorting Option';
            app.AscendingButton = uitogglebutton(app.SortingOptionButtonGroup);
            app.AscendingButton.Text = 'Ascending';
            app.AscendingButton.Value = true;
            app.DescendingButton = uitogglebutton(app.SortingOptionButtonGroup);
            app.DescendingButton.Text = 'Descending';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'Coverage Charge', 'Patient Payment', '# of Different Treatment'};
            app.DropDown.Value = 'Coverage Charge';
            app.RunButton = uibutton(app.Panel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunProg, true);
            app.RunButton.Text = 'Run';
            app.GraphDisplayButton = uibutton(app.Panel, 'push');
            app.GraphDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @GraphDisp, true);
            app.GraphDisplayButton.Text = 'Graph Display';
            app.SaveasTextFileButton = uibutton(app.Panel, 'push');
            app.SaveasTextFileButton.ButtonPushedFcn = createCallbackFcn(app, @TxtSave, true);
            app.SaveasTextFileButton.Text = 'Save as Text File';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_3 = uipanel(app.Panel_2);
            app.Label = uilabel(app.Panel_3);
            app.Label.Text = '';
            app.UITable = uitable(app.Panel_2);
            app.UITable.ColumnName = {'State'; 'Cost'};
            app.UITable.RowName = {};
            app.MedicareCostLabel = uilabel(app.Panel_2);
            app.MedicareCostLabel.Text = 'Medicare Cost';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BaseDesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end